classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BalancelectroniqueasservieOutildeCAOUIFigure  matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        MainTabGroup                    matlab.ui.container.TabGroup
        EditeurdesimulationTab          matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        ParamtresdusimulateurPanel      matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        ChargerparamtrespardfautButton  matlab.ui.control.Button
        SauvegarderButton               matlab.ui.control.Button
        ChargerparamtrespersonnalissButton  matlab.ui.control.Button
        AmplificateurdepuissanceLabel   matlab.ui.control.Label
        GaindelamplificateurEditFieldLabel  matlab.ui.control.Label
        GaindelamplificateurEditField   matlab.ui.control.NumericEditField
        CapteurdecourantLabel           matlab.ui.control.Label
        GainVALabel                     matlab.ui.control.Label
        GainCapteurCourantEditField     matlab.ui.control.NumericEditField
        ActionneurLabel                 matlab.ui.control.Label
        NombredetoursdelabobineLabel    matlab.ui.control.Label
        NombreDeTours                   matlab.ui.control.NumericEditField
        DensitdefluxTLabel              matlab.ui.control.Label
        DensiteDeFlux                   matlab.ui.control.NumericEditField
        RayondelabobinemLabel           matlab.ui.control.Label
        RayonDeLaBobine                 matlab.ui.control.NumericEditField
        ResistancedelabobineOhmLabel    matlab.ui.control.Label
        ResistanceDeLaBobine            matlab.ui.control.NumericEditField
        RsistancedelamplificateurOhmLabel  matlab.ui.control.Label
        ResistanceEquivalente           matlab.ui.control.NumericEditField
        InductancedelabobineuHLabel     matlab.ui.control.Label
        InductanceDeLaBobine            matlab.ui.control.NumericEditField
        RgulateurdepositionLabel        matlab.ui.control.Label
        ConstanteproportionnelleEditFieldLabel  matlab.ui.control.Label
        ConstanteProportionnelleEditField  matlab.ui.control.NumericEditField
        ConstanteintgraleEditFieldLabel  matlab.ui.control.Label
        ConstanteIntegraleEditField     matlab.ui.control.NumericEditField
        RsolutiondelapositionbitsLabel  matlab.ui.control.Label
        ResolutionADCEditField          matlab.ui.control.NumericEditField
        RsolutiondelacommandebitsLabel  matlab.ui.control.Label
        ResolutionDACEditField          matlab.ui.control.NumericEditField
        LameLabel                       matlab.ui.control.Label
        LargeurdelalamemLabel           matlab.ui.control.Label
        LargeurLame                     matlab.ui.control.NumericEditField
        LongueurdelalamemLabel          matlab.ui.control.Label
        LogueurLame                     matlab.ui.control.NumericEditField
        ModuledeYoungPaLabel            matlab.ui.control.Label
        ModuleDeYoung                   matlab.ui.control.NumericEditField
        Coefficientdefrottementm1Label  matlab.ui.control.Label
        CoefficientFrottement           matlab.ui.control.NumericEditField
        HauteurdelalamemLabel_6         matlab.ui.control.Label
        HauteurLame                     matlab.ui.control.NumericEditField
        EntresPanel                     matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        MassedposegEditFieldLabel       matlab.ui.control.Label
        MassedposegEditField            matlab.ui.control.NumericEditField
        ChangementDeMasseCheckBox       matlab.ui.control.CheckBox
        NouvellemassegEditFieldLabel    matlab.ui.control.Label
        NouvelleMasseEditField          matlab.ui.control.NumericEditField
        MomentdarrivedelamassesEditFieldLabel  matlab.ui.control.Label
        MomentArriveeEditField          matlab.ui.control.NumericEditField
        MassetaregEditFieldLabel        matlab.ui.control.Label
        MasseTareEditField              matlab.ui.control.NumericEditField
        SimulationPanel                 matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        LancerlasimulationButton        matlab.ui.control.Button
        DuredelasimulationsEditField_2Label  matlab.ui.control.Label
        DuredelasimulationsEditField_2  matlab.ui.control.EditField
        BalayagedeparamtresPanel        matlab.ui.container.Panel
        GridLayout6                     matlab.ui.container.GridLayout
        ParamtrebalayDropDown_2Label    matlab.ui.control.Label
        ParametreBalayeDropDown         matlab.ui.control.DropDown
        BalayageButton                  matlab.ui.control.Button
        ValeurssimulerEditFieldLabel    matlab.ui.control.Label
        BalayageValeursEditField        matlab.ui.control.EditField
        DescirptionParametreBalayeLabel  matlab.ui.control.Label
        DescriptionLabel                matlab.ui.control.Label
        OptionsdaffichagePanel          matlab.ui.container.Panel
        GridLayout7                     matlab.ui.container.GridLayout
        ModeDropDown_2Label             matlab.ui.control.Label
        ModeDropDown_2                  matlab.ui.control.DropDown
        UnitsdemesureDropDown_2Label    matlab.ui.control.Label
        UnitsdemesureDropDown_2         matlab.ui.control.DropDown
        TypedepicesDropDownLabel        matlab.ui.control.Label
        TypeDePiecesDropDown            matlab.ui.control.DropDown
        ResultatsTab                    matlab.ui.container.Tab
        GridLayout8                     matlab.ui.container.GridLayout
        PerformancePanel                matlab.ui.container.Panel
        GridLayout11                    matlab.ui.container.GridLayout
        TempsStabilisationLabel         matlab.ui.control.Label
        JustesseLabel                   matlab.ui.control.Label
        PrecisionLabel                  matlab.ui.control.Label
        Execution_Time                  matlab.ui.control.Label
        GraphiquesPanel                 matlab.ui.container.Panel
        GridLayout9                     matlab.ui.container.GridLayout
        AxesMasseReelle                 matlab.ui.control.UIAxes
        AxesMasseMesuree                matlab.ui.control.UIAxes
        AxesLame                        matlab.ui.control.UIAxes
        AxesCommande                    matlab.ui.control.UIAxes
        AffichagegraphiquePanel         matlab.ui.container.Panel
        GridLayout10                    matlab.ui.container.GridLayout
        LCD                             matlab.ui.control.Label
        FichiersPanel                   matlab.ui.container.Panel
        GridLayout12                    matlab.ui.container.GridLayout
        ChargerResultatsButton          matlab.ui.control.Button
        SauvegarderResultatsButton      matlab.ui.control.Button
        ComparaisonTab                  matlab.ui.container.Tab
        GridLayout13                    matlab.ui.container.GridLayout
        FichiersPanel_2                 matlab.ui.container.Panel
        GridLayout14                    matlab.ui.container.GridLayout
        ChargerPremierModeleButton      matlab.ui.control.Button
        ChargerSecondModeleButton       matlab.ui.control.Button
        GraphiquesPanel_2               matlab.ui.container.Panel
        GridLayout15                    matlab.ui.container.GridLayout
        AxesComparaisonMasseReelle      matlab.ui.control.UIAxes
        AxesComparaisonCommande         matlab.ui.control.UIAxes
        AxesComparaisonMasseMesuree     matlab.ui.control.UIAxes
        AxesComparaisonLame             matlab.ui.control.UIAxes
        PerformancedupremiermodlePanel  matlab.ui.container.Panel
        GridLayout16                    matlab.ui.container.GridLayout
        JustesseComparaisonLabel1       matlab.ui.control.Label
        PrecisionComparaisonLabel1      matlab.ui.control.Label
        TempsStabilisationComparaisonLabel1  matlab.ui.control.Label
        PerformancedusecondmodlePanel   matlab.ui.container.Panel
        GridLayout17                    matlab.ui.container.GridLayout
        JustesseComparaisonLabel2       matlab.ui.control.Label
        TempsStabilisationComparaisonLabel2  matlab.ui.control.Label
        PrecisionComparaisonLabel2      matlab.ui.control.Label
    end

    
    properties (Access = private)
        x % Description
        DesignData % Data Dictionnary section for all model data (parameters)
        SimulationOutput % Description
        SimulationStopTime = 24; % Description
        MasseDeposee = 50 % La 1re masse déposée
        NouvelleMasseDeposee = 25 % La 2e masse deposée
        TempsNouvelleMasseDeposee = 16 % L'instant, en seconde, où la 2e masse est déposée
        CurrentSimulationOutputs % Description
        PremierModeleSimulationOutputs % Description
        SecondModeleSimulationOutputs % Description
        myTimer
        ParametresBalayes = containers.Map( ...
                    ["K_amp", "K_c","N_bobine","B_bobine","r_bobine","R_bobine","R_eq","L_bobine","Kp","Ki","adc_entrees_resolution","dac_commande_resolution"], ...
               {
               [1, 2, 4.7, 7, 10], ...
               [1, 1.422, 1.7, 2, 3], ...
               [40, 50, 66, 80, 90], ...
               [0.08, 0.09, 0.1128, 0.12, 0.13], ...
               [0.010, 0.015, 0.0203, 0.025, 0.03], ...
               [0.8, 1.2, 1.424, 1.6, 2], ...
               [0.8, 1.0, 1.1, 1.2, 1.4], ...
               [100, 150, 212, 250, 300], ...
               [0.0001, 0.0003, 0.001, 0.003, 0.01], ...
               [0.02, 0.05, 0.1408, 0.5, 1.2], ...
               [6, 8, 10, 12, 14], ...
               [6, 8, 10, 12, 14], ...
               });
       NomsParametresBalayes = containers.Map( ...
                    ["K_amp", "K_c","N_bobine","B_bobine","r_bobine","R_bobine","R_eq","L_bobine","Kp","Ki","adc_entrees_resolution","dac_commande_resolution"], ...
                    ["Gain de l'amplificateur","Gain du capteur de courant","Nombre de tours", ...
                    "Densité de flux magnétique", "Rayon de la bobine", "Résistance de la bobine", "Résistance de l'amplificateur", ...
                    "Inductance de la bobine", "Constante proportionnelle", "Constante intégrale", ...
                    "Résolution de la position (bits)", "Résolution de la commande (bits)"] ...
           )
        MasseTare = 0 % Description
    end
    
    methods (Access = private)
        function load_design_data(app, filepath)
            importFromFile(app.DesignData, filepath, 'existingVarsAction','overwrite');
            app.reset_parameters();
        end
        
        function load_default_design_data(app)
            app.load_design_data("default_design_data.mat")
        end
        
        function reset_parameters(app)
            app.GaindelamplificateurEditField.Value = getValue(getEntry(app.DesignData, "K_amp"));
            app.GainCapteurCourantEditField.Value = getValue(getEntry(app.DesignData, "K_c"));
            app.NombreDeTours.Value = getValue(getEntry(app.DesignData, "N_bobine"));
            app.DensiteDeFlux.Value = getValue(getEntry(app.DesignData, "B_bobine"));
            app.RayonDeLaBobine.Value = getValue(getEntry(app.DesignData, "r_bobine"));
            app.ResistanceDeLaBobine.Value = getValue(getEntry(app.DesignData, "R_bobine"));
            app.ResistanceEquivalente.Value = getValue(getEntry(app.DesignData, "R_eq"));
            app.InductanceDeLaBobine.Value = getValue(getEntry(app.DesignData, "L_bobine"));
            app.HauteurLame.Value = getValue(getEntry(app.DesignData, "h"));
            app.LargeurLame.Value = getValue(getEntry(app.DesignData, "l"));
            app.LogueurLame.Value = getValue(getEntry(app.DesignData, "L"));
            app.ModuleDeYoung.Value = getValue(getEntry(app.DesignData, "E"));
            app.CoefficientFrottement.Value = getValue(getEntry(app.DesignData, "gamma"));
            app.ConstanteProportionnelleEditField.Value = getValue(getEntry(app.DesignData, "Kp"));
            app.ConstanteIntegraleEditField.Value = getValue(getEntry(app.DesignData, "Ki"));
            app.ResolutionADCEditField.Value = getValue(getEntry(app.DesignData, "adc_entrees_resolution"));
            app.ResolutionDACEditField.Value = getValue(getEntry(app.DesignData, "dac_commande_resolution"));
        end
        
        function UpdateResultats(app)
            plot(app.AxesMasseReelle, app.CurrentSimulationOutputs.masse_reelle(1, :), app.CurrentSimulationOutputs.masse_reelle(2, :))
            plot(app.AxesMasseMesuree, app.CurrentSimulationOutputs.masse_mesuree(1, :), app.CurrentSimulationOutputs.masse_mesuree(2, :))
            plot(app.AxesLame, app.CurrentSimulationOutputs.deflexion(1, :), app.CurrentSimulationOutputs.deflexion(2, :))
            plot(app.AxesCommande, app.CurrentSimulationOutputs.commande(1, :), app.CurrentSimulationOutputs.commande(2, :))

            app.TempsStabilisationLabel.Text = sprintf("Temps de stabilisation: %0.3f s", app.CurrentSimulationOutputs.T_stabilisation);
            app.PrecisionLabel.Text = sprintf("Précision: %0.4f g", app.CurrentSimulationOutputs.precision);
            app.JustesseLabel.Text = sprintf("Justesse: %0.4f %%", app.CurrentSimulationOutputs.justesse * 100);
            
            try
                app.Execution_Time.Text = sprintf("Temps d'exécution: %4.2f s", etime(clock,app.myTimer));
            catch
            end
            
            app.LCD.Text = app.CurrentSimulationOutputs.affichage_graphique;
        end
        
        function UpdateComparaison(app)
            firstModelColor = [0.85,0.33,0.10];
            secondModelColor = [0.07,0.62,1.00];
            
            cla(app.AxesComparaisonMasseReelle)
            cla(app.AxesComparaisonMasseMesuree)
            cla(app.AxesComparaisonLame)
            cla(app.AxesComparaisonCommande)
            hold(app.AxesComparaisonMasseReelle, 'on')
            hold(app.AxesComparaisonMasseMesuree, 'on')
            hold(app.AxesComparaisonLame, 'on')
            hold(app.AxesComparaisonCommande, 'on')
            
            if isa(app.PremierModeleSimulationOutputs, "struct")
                plot(app.AxesComparaisonMasseReelle, app.PremierModeleSimulationOutputs.masse_reelle(1, :), app.PremierModeleSimulationOutputs.masse_reelle(2, :), 'Color', firstModelColor)
                plot(app.AxesComparaisonMasseMesuree, app.PremierModeleSimulationOutputs.masse_mesuree(1, :), app.PremierModeleSimulationOutputs.masse_mesuree(2, :), 'Color', firstModelColor)
                plot(app.AxesComparaisonLame, app.PremierModeleSimulationOutputs.deflexion(1, :), app.PremierModeleSimulationOutputs.deflexion(2, :), 'Color', firstModelColor)
                plot(app.AxesComparaisonCommande, app.PremierModeleSimulationOutputs.commande(1, :), app.PremierModeleSimulationOutputs.commande(2, :), 'Color', firstModelColor)
                
                app.TempsStabilisationComparaisonLabel1.Text = sprintf("Temps de stabilisation: %0.3f s", app.PremierModeleSimulationOutputs.T_stabilisation);
                app.PrecisionComparaisonLabel1.Text = sprintf("Précision: %0.4f g", app.PremierModeleSimulationOutputs.precision);
                app.JustesseComparaisonLabel1.Text = sprintf("Justesse: %0.4f %%", app.PremierModeleSimulationOutputs.justesse * 100);
            end
            
            if isa(app.SecondModeleSimulationOutputs, "struct")
                plot(app.AxesComparaisonMasseReelle, app.SecondModeleSimulationOutputs.masse_reelle(1, :), app.SecondModeleSimulationOutputs.masse_reelle(2, :), 'Color', secondModelColor)
                plot(app.AxesComparaisonMasseMesuree, app.SecondModeleSimulationOutputs.masse_mesuree(1, :), app.SecondModeleSimulationOutputs.masse_mesuree(2, :), 'Color', secondModelColor)
                plot(app.AxesComparaisonLame, app.SecondModeleSimulationOutputs.deflexion(1, :), app.SecondModeleSimulationOutputs.deflexion(2, :), 'Color', secondModelColor)
                plot(app.AxesComparaisonCommande, app.SecondModeleSimulationOutputs.commande(1, :), app.SecondModeleSimulationOutputs.commande(2, :), 'Color', secondModelColor)
                
                app.TempsStabilisationComparaisonLabel2.Text = sprintf("Temps de stabilisation: %0.3f s", app.SecondModeleSimulationOutputs.T_stabilisation);
                app.PrecisionComparaisonLabel2.Text = sprintf("Précision: %0.4f g", app.SecondModeleSimulationOutputs.precision);
                app.JustesseComparaisonLabel2.Text = sprintf("Justesse: %0.4f %%", app.SecondModeleSimulationOutputs.justesse * 100);
            end
            
        end
        
        function FocusMainWindow(app)
            % Fixes bug (in App Designer?) that the main window becomes out
            % of focus after open a file through the file dialog.
            figure(app.BalancelectroniqueasservieOutildeCAOUIFigure)
        end
        
        function ResetBalayageValeurs(app)
            app.BalayageValeursEditField.Value = num2str(app.ParametresBalayes(app.ParametreBalayeDropDown.Value));
            app.DescirptionParametreBalayeLabel.Text = app.NomsParametresBalayes(app.ParametreBalayeDropDown.Value);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            dataDictionnary = Simulink.data.dictionary.open("dict.sldd");
            app.DesignData = getSection(dataDictionnary, "Design Data");
            app.load_default_design_data();
            app.ResetBalayageValeurs();
            
            % set_param("main", 'SimulationCommand', 'stop');
        end

        % Callback function
        function xEditFieldValueChanged(app, event)
            value = app.xEditField.Value;
            app.x = value;
            disp(app.x);
            setValue(getEntry(app.DesignData, "x"), value);
        end

        % Button pushed function: LancerlasimulationButton
        function LancerlasimulationButtonPushed(app, event)
            app.LancerlasimulationButton.Enable = "off";
            app.myTimer = clock;
            progressBar = uiprogressdlg(app.BalancelectroniqueasservieOutildeCAOUIFigure, "Title",'Simulation en cours...', "Indeterminate", "on");
            
            open_system("main.slx", 'loadonly')
            
            inputs = Simulink.SimulationInput("main");
            inputs = inputs.setBlockParameter('main/Type De Pièces','Value',app.TypeDePiecesDropDown.Value);
            inputs = inputs.setBlockParameter('main/Index unité de mesure','Value',app.UnitsdemesureDropDown_2.Value);
            inputs = inputs.setBlockParameter("main/Index du mode d'affichage",'Value',app.ModeDropDown_2.Value);
            inputs = inputs.setVariable("masse_deposee", app.MasseDeposee, "Workspace", "main");
            inputs = inputs.setVariable("masse_tare", app.MasseTare, "Workspace", "main");
            
            if app.ChangementDeMasseCheckBox.Value == true
                inputs = inputs.setVariable("nouvelle_masse_deposee", app.NouvelleMasseDeposee, "Workspace", "main");
            else
                inputs = inputs.setVariable("nouvelle_masse_deposee", 0, "Workspace", "main");
            end
            inputs = inputs.setVariable("temps_nouvelle_masse_deposee", app.TempsNouvelleMasseDeposee, "Workspace", "main");
            
            set_param("main", 'StopTime', string(app.SimulationStopTime))
            simulationOutput = sim(inputs);
            
            masseReelle = simulationOutput.logsout.getElement("masse_reelle").Values;
            masseMesuree = simulationOutput.logsout.getElement("masse_mesuree").Values;
            deflexion = simulationOutput.logsout.getElement("deflexion").Values;
            commande = simulationOutput.logsout.getElement("commande").Values;
            app.CurrentSimulationOutputs = struct( ...
                'masse_reelle', [masseReelle.Time'; masseReelle.Data(:)'], ...
                'masse_mesuree', [masseMesuree.Time'; masseMesuree.Data(:)'], ...
                'deflexion', [deflexion.Time'; deflexion.Data(:)' .* 1000], ...
                'commande', [commande.Time'; commande.Data(:)'], ...
                "T_stabilisation", simulationOutput.T_stabilisation, ...
                "justesse", simulationOutput.justesse, ...
                "precision", simulationOutput.precision, ...
                "affichage_graphique", char(simulationOutput.Affichage_Graphique), ...
                "Current_Execution_Time", simulationOutput.Current_Execution_Time ...
            );
            
            app.UpdateResultats()
            app.MainTabGroup.SelectedTab = app.MainTabGroup.Children(2);
            
            close(progressBar)
            app.LancerlasimulationButton.Enable = "on";
            app.SauvegarderResultatsButton.Enable = "on";
        end

        % Button pushed function: SauvegarderButton
        function SauvegarderButtonPushed(app, event)
            [file, path] = uiputfile("*.mat", "Sauvegarder les paramètres du simulateur");
            app.FocusMainWindow();
            
            if ~isequal(file, 0)
                exportToFile(app.DesignData, fullfile(path, file));
            end
        end

        % Button pushed function: ChargerparamtrespersonnalissButton
        function ChargerparamtrespersonnalissButtonPushed(app, event)
            [file, path] = uigetfile("*.mat", "Charger les paramètres du simulateur");
            app.FocusMainWindow();
            
            if ~isequal(file, 0)
                importFromFile(app.DesignData, fullfile(path, file), 'existingVarsAction','overwrite')
                app.reset_parameters()
            end
        end

        % Button pushed function: ChargerparamtrespardfautButton
        function ChargerparamtrespardfautButtonPushed(app, event)
            app.load_default_design_data()
        end

        % Value changed function: GaindelamplificateurEditField
        function GaindelamplificateurEditFieldValueChanged(app, event)
            value = app.GaindelamplificateurEditField.Value;
            setValue(getEntry(app.DesignData, "K_amp"), value);
        end

        % Value changed function: DuredelasimulationsEditField_2
        function DuredelasimulationsEditField_2ValueChanged(app, event)
            value = app.DuredelasimulationsEditField_2.Value;
            app.SimulationStopTime = value;
        end

        % Value changed function: MassedposegEditField
        function MassedposegEditFieldValueChanged(app, event)
            value = app.MassedposegEditField.Value;
            app.MasseDeposee = value;
        end

        % Button pushed function: BalayageButton
        function BalayageButtonPushed(app, event)
            app.BalayageButton.Enable = "off";
            
            path = uigetdir(pwd(), "Sélectionner un dossier pour la sauvegarde des résultats");
            app.FocusMainWindow();
            
            if ~isequal(path, 0)
                    
                progressBar = uiprogressdlg(app.BalancelectroniqueasservieOutildeCAOUIFigure, "Title", "Balayage en cours...", "Message", ...
                    "L'exécution des simulations va prendre quelques minutes", "Indeterminate", "on");
                
                parametreChoisi = app.ParametreBalayeDropDown.Value;
                valeursParametre = app.ParametresBalayes(parametreChoisi);
                
                % ancienneValeurParametre = getValue(getEntry(app.DesignData, parametreChoisi));
                % setValue(getEntry(app.DesignData, parametreChoisi), value)
                
                for i = 1:length(valeursParametre)
                    entreeSimulation(i) = Simulink.SimulationInput("main");
                    
                    % set variable in global workspace
                    entreeSimulation(i) = setVariable(entreeSimulation(i), parametreChoisi, valeursParametre(i));
                end
            
                rawSimulationOutputs = sim(entreeSimulation);
            
                % Affichage
                justesses = [];
                T_stabilisations = [];
                precisions = [];
                for i = 1:length(valeursParametre)
                    justesses(i) = rawSimulationOutputs(i).justesse;
                    T_stabilisations(i) = rawSimulationOutputs(i).T_stabilisation;
                    precisions(i) = rawSimulationOutputs(i).precision;
                    
                    masseReelle = rawSimulationOutputs(i).logsout.getElement("masse_reelle").Values;
                    masseMesuree = rawSimulationOutputs(i).logsout.getElement("masse_mesuree").Values;
                    deflexion = rawSimulationOutputs(i).logsout.getElement("deflexion").Values;
                    commande = rawSimulationOutputs(i).logsout.getElement("commande").Values;
                    simulationOutputs = struct( ...
                        'masse_reelle', [masseReelle.Time'; masseReelle.Data(:)'], ...
                        'masse_mesuree', [masseMesuree.Time'; masseMesuree.Data(:)'], ...
                        'deflexion', [deflexion.Time'; deflexion.Data(:)' .* 1000], ...
                        'commande', [commande.Time'; commande.Data(:)'], ...
                        "T_stabilisation", rawSimulationOutputs(i).T_stabilisation, ...
                        "justesse", rawSimulationOutputs(i).justesse, ...
                        "precision", rawSimulationOutputs(i).precision, ...
                        "affichage_graphique", char(rawSimulationOutputs(i).Affichage_Graphique), ...
                        "Current_Execution_Time", rawSimulationOutputs(i).Current_Execution_Time ...
                    );
                    
                    save(fullfile(path, sprintf("resultats_%d.mat", i)), "simulationOutputs");
                end
                
                % affichage plot
                figure;
                subplot(3,1,1);
                plot(valeursParametre, justesses, "o-");
                title("Justesse en fonction de " + parametreChoisi, "Interpreter", "none");
                xlabel(parametreChoisi, "Interpreter", "none");
                ylabel("Justesse", "Interpreter", "none");
            
                subplot(3,1,2);
                plot(valeursParametre, T_stabilisations, "o-");
                title("Temps de stabilisation en fonction de " + parametreChoisi, "Interpreter", "none");
                xlabel(parametreChoisi, "Interpreter", "none");
                ylabel("Temps de stabilisation [s]", "Interpreter", "none");
            
                subplot(3,1,3);
                plot(valeursParametre, precisions, "o-");
                title("Précision en fonction de " + parametreChoisi, "Interpreter", "none");
                xlabel(parametreChoisi, "Interpreter", "none");
                ylabel("Précision [g]", "Interpreter", "none");
                
                close(progressBar)
            end
            app.BalayageButton.Enable = "on";
            
        end

        % Value changed function: ChangementDeMasseCheckBox
        function ChangementDeMasseCheckBoxValueChanged(app, event)
            value = app.ChangementDeMasseCheckBox.Value;
            
            if value == true
                app.NouvelleMasseEditField.Enable = "on";
                app.MomentArriveeEditField.Enable = "on";
            else
                app.NouvelleMasseEditField.Enable = "off";
                app.MomentArriveeEditField.Enable = "off";
            end
        end

        % Value changed function: NouvelleMasseEditField
        function NouvelleMasseEditFieldValueChanged(app, event)
            value = app.NouvelleMasseEditField.Value;
            app.NouvelleMasseDeposee = value;
        end

        % Value changed function: MomentArriveeEditField
        function MomentArriveeEditFieldValueChanged(app, event)
            value = app.MomentArriveeEditField.Value;
            app.TempsNouvelleMasseDeposee = value;
        end

        % Button pushed function: SauvegarderResultatsButton
        function SauvegarderResultatsButtonPushed(app, event)
            [file, path] = uiputfile("*.mat", "Sauvegarder les résultats de la simulation");
            app.FocusMainWindow();
            
            if ~isequal(file, 0)
                simulationOutputs = app.CurrentSimulationOutputs;
                save(fullfile(path, file), "simulationOutputs");
            end
        end

        % Button pushed function: ChargerResultatsButton
        function ChargerResultatsButtonPushed(app, event)
            [file, path] = uigetfile("*.mat", "Charger les résultats de la simulation");
            app.FocusMainWindow();
            
            if ~isequal(file, 0)
                load(fullfile(path, file), "simulationOutputs")
                app.CurrentSimulationOutputs = simulationOutputs;
                app.UpdateResultats()
            end
        end

        % Button pushed function: ChargerPremierModeleButton
        function ChargerPremierModeleButtonPushed(app, event)
            [file, path] = uigetfile("*.mat", "Charger les résultats du premier modèle");
            app.FocusMainWindow();
            
            if ~isequal(file, 0)
                load(fullfile(path, file), "simulationOutputs")
                app.PremierModeleSimulationOutputs = simulationOutputs;
                app.UpdateComparaison()
            end
        end

        % Button pushed function: ChargerSecondModeleButton
        function ChargerSecondModeleButtonPushed(app, event)
            [file, path] = uigetfile("*.mat", "Charger les résultats du second modèle");
            app.FocusMainWindow();
            
            if ~isequal(file, 0)
                load(fullfile(path, file), "simulationOutputs")
                app.SecondModeleSimulationOutputs = simulationOutputs;
                app.UpdateComparaison()
            end
        end

        % Value changed function: GainCapteurCourantEditField
        function GainCapteurCourantEditFieldValueChanged(app, event)
            value = app.GainCapteurCourantEditField.Value;
            setValue(getEntry(app.DesignData, "K_c"), value);
        end

        % Value changed function: NombreDeTours
        function NombreDeToursValueChanged(app, event)
            value = app.NombreDeTours.Value;
            setValue(getEntry(app.DesignData, "N_bobine"), value);
        end

        % Value changed function: DensiteDeFlux
        function DensiteDeFluxValueChanged(app, event)
            value = app.DensiteDeFlux.Value;
            setValue(getEntry(app.DesignData, "B_bobine"), value);
        end

        % Value changed function: RayonDeLaBobine
        function RayonDeLaBobineValueChanged(app, event)
            value = app.RayonDeLaBobine.Value;
            setValue(getEntry(app.DesignData, "r_bobine"), value);
        end

        % Callback function
        function InductanceDeLaBobineValueChanged(app, event)
            value = app.ResistanceDeLaBobine.Value;
            setValue(getEntry(app.DesignData, "L_bobine"), value);
        end

        % Value changed function: ResistanceDeLaBobine
        function ResistanceDeLaBobineValueChanged(app, event)
            value = app.ResistanceDeLaBobine.Value;
            setValue(getEntry(app.DesignData, "R_bobine"), value);
        end

        % Value changed function: ResistanceEquivalente
        function ResistanceEquivalenteValueChanged(app, event)
            value = app.ResistanceEquivalente.Value;
            setValue(getEntry(app.DesignData, "R_eq"), value);
        end

        % Value changed function: ConstanteProportionnelleEditField
        function ConstanteProportionnelleEditFieldValueChanged(app, event)
            value = app.ConstanteProportionnelleEditField.Value;
            setValue(getEntry(app.DesignData, "Kp"), value)
        end

        % Value changed function: ConstanteIntegraleEditField
        function ConstanteIntegraleEditFieldValueChanged(app, event)
            value = app.ConstanteIntegraleEditField.Value;
            setValue(getEntry(app.DesignData, "Ki"), value)
        end

        % Value changed function: ResolutionADCEditField
        function ResolutionADCEditFieldValueChanged(app, event)
            value = app.ResolutionADCEditField.Value;
            setValue(getEntry(app.DesignData, "adc_entrees_resolution"), value)
        end

        % Value changed function: ResolutionDACEditField
        function ResolutionDACEditFieldValueChanged(app, event)
            value = app.ResolutionDACEditField.Value;
            setValue(getEntry(app.DesignData, "dac_commande_resolution"), value)
        end

        % Value changed function: BalayageValeursEditField
        function BalayageValeursEditFieldValueChanged(app, event)
            value = app.BalayageValeursEditField.Value;
            app.ParametresBalayes(app.ParametreBalayeDropDown.Value) = str2num(value);
        end

        % Value changed function: ParametreBalayeDropDown
        function ParametreBalayeDropDownValueChanged(app, event)
            app.ResetBalayageValeurs();
        end

        % Value changed function: MasseTareEditField
        function MasseTareEditFieldValueChanged(app, event)
            value = app.MasseTareEditField.Value;
            app.MasseTare = value;
        end

        % Value changed function: ModeDropDown_2
        function ModeDropDown_2ValueChanged(app, event)
            value = app.ModeDropDown_2.Value;
            if value == "0"
                app.TypeDePiecesDropDown.Enable = "off";
            else
                app.TypeDePiecesDropDown.Enable = "on";
            end
        end

        % Value changed function: HauteurLame
        function HauteurLameValueChanged(app, event)
            value = app.HauteurLame.Value;
            setValue(getEntry(app.DesignData, "h"), value);
        end

        % Value changed function: LargeurLame
        function LargeurLameValueChanged(app, event)
            value = app.LargeurLame.Value;
            setValue(getEntry(app.DesignData, "l"), value);
        end

        % Value changed function: LogueurLame
        function LogueurLameValueChanged(app, event)
            value = app.LogueurLame.Value;
            setValue(getEntry(app.DesignData, "L"), value);
        end

        % Value changed function: ModuleDeYoung
        function ModuleDeYoungValueChanged(app, event)
            value = app.ModuleDeYoung.Value;
            setValue(getEntry(app.DesignData, "E"), value);
        end

        % Value changed function: CoefficientFrottement
        function CoefficientFrottementValueChanged(app, event)
            value = app.CoefficientFrottement.Value;
            setValue(getEntry(app.DesignData, "gamma"), value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BalancelectroniqueasservieOutildeCAOUIFigure = uifigure('Visible', 'off');
            app.BalancelectroniqueasservieOutildeCAOUIFigure.Name = 'Balance électronique asservie - Outil de CAO';
            app.GridLayout = uigridlayout(app.BalancelectroniqueasservieOutildeCAOUIFigure);
            app.MainTabGroup = uitabgroup(app.GridLayout);
            app.EditeurdesimulationTab = uitab(app.MainTabGroup);
            app.EditeurdesimulationTab.Title = 'Éditeur de simulation';
            app.GridLayout2 = uigridlayout(app.EditeurdesimulationTab);
            app.ParamtresdusimulateurPanel = uipanel(app.GridLayout2);
            app.ParamtresdusimulateurPanel.Title = 'Paramètres du simulateur';
            app.GridLayout4 = uigridlayout(app.ParamtresdusimulateurPanel);
            app.ChargerparamtrespardfautButton = uibutton(app.GridLayout4, 'push');
            app.ChargerparamtrespardfautButton.ButtonPushedFcn = createCallbackFcn(app, @ChargerparamtrespardfautButtonPushed, true);
            app.ChargerparamtrespardfautButton.Text = 'Charger paramètres par défaut';
            app.SauvegarderButton = uibutton(app.GridLayout4, 'push');
            app.SauvegarderButton.ButtonPushedFcn = createCallbackFcn(app, @SauvegarderButtonPushed, true);
            app.SauvegarderButton.Text = 'Sauvegarder';
            app.ChargerparamtrespersonnalissButton = uibutton(app.GridLayout4, 'push');
            app.ChargerparamtrespersonnalissButton.ButtonPushedFcn = createCallbackFcn(app, @ChargerparamtrespersonnalissButtonPushed, true);
            app.ChargerparamtrespersonnalissButton.Text = 'Charger paramètres personnalisés';
            app.AmplificateurdepuissanceLabel = uilabel(app.GridLayout4);
            app.AmplificateurdepuissanceLabel.Text = 'Amplificateur de puissance';
            app.GaindelamplificateurEditFieldLabel = uilabel(app.GridLayout4);
            app.GaindelamplificateurEditFieldLabel.Text = 'Gain de l''amplificateur';
            app.GaindelamplificateurEditField = uieditfield(app.GridLayout4, 'numeric');
            app.GaindelamplificateurEditField.ValueChangedFcn = createCallbackFcn(app, @GaindelamplificateurEditFieldValueChanged, true);
            app.CapteurdecourantLabel = uilabel(app.GridLayout4);
            app.CapteurdecourantLabel.Text = 'Capteur de courant';
            app.GainVALabel = uilabel(app.GridLayout4);
            app.GainVALabel.Text = 'Gain (V/A)';
            app.GainCapteurCourantEditField = uieditfield(app.GridLayout4, 'numeric');
            app.GainCapteurCourantEditField.ValueChangedFcn = createCallbackFcn(app, @GainCapteurCourantEditFieldValueChanged, true);
            app.ActionneurLabel = uilabel(app.GridLayout4);
            app.ActionneurLabel.Text = 'Actionneur';
            app.NombredetoursdelabobineLabel = uilabel(app.GridLayout4);
            app.NombredetoursdelabobineLabel.Text = 'Nombre de tours de la bobine';
            app.NombreDeTours = uieditfield(app.GridLayout4, 'numeric');
            app.NombreDeTours.ValueChangedFcn = createCallbackFcn(app, @NombreDeToursValueChanged, true);
            app.DensitdefluxTLabel = uilabel(app.GridLayout4);
            app.DensitdefluxTLabel.Text = 'Densité de flux (T)';
            app.DensiteDeFlux = uieditfield(app.GridLayout4, 'numeric');
            app.DensiteDeFlux.ValueChangedFcn = createCallbackFcn(app, @DensiteDeFluxValueChanged, true);
            app.RayondelabobinemLabel = uilabel(app.GridLayout4);
            app.RayondelabobinemLabel.Text = 'Rayon de la bobine (m)';
            app.RayonDeLaBobine = uieditfield(app.GridLayout4, 'numeric');
            app.RayonDeLaBobine.ValueChangedFcn = createCallbackFcn(app, @RayonDeLaBobineValueChanged, true);
            app.ResistancedelabobineOhmLabel = uilabel(app.GridLayout4);
            app.ResistancedelabobineOhmLabel.Text = 'Resistance de la bobine (Ohm)';
            app.ResistanceDeLaBobine = uieditfield(app.GridLayout4, 'numeric');
            app.ResistanceDeLaBobine.ValueChangedFcn = createCallbackFcn(app, @ResistanceDeLaBobineValueChanged, true);
            app.RsistancedelamplificateurOhmLabel = uilabel(app.GridLayout4);
            app.RsistancedelamplificateurOhmLabel.Text = 'Résistance de l''amplificateur (Ohm)';
            app.ResistanceEquivalente = uieditfield(app.GridLayout4, 'numeric');
            app.ResistanceEquivalente.ValueChangedFcn = createCallbackFcn(app, @ResistanceEquivalenteValueChanged, true);
            app.InductancedelabobineuHLabel = uilabel(app.GridLayout4);
            app.InductancedelabobineuHLabel.Text = 'Inductance de la bobine (uH)';
            app.InductanceDeLaBobine = uieditfield(app.GridLayout4, 'numeric');
            app.RgulateurdepositionLabel = uilabel(app.GridLayout4);
            app.RgulateurdepositionLabel.Text = 'Régulateur de position';
            app.ConstanteproportionnelleEditFieldLabel = uilabel(app.GridLayout4);
            app.ConstanteproportionnelleEditFieldLabel.Text = 'Constante proportionnelle';
            app.ConstanteProportionnelleEditField = uieditfield(app.GridLayout4, 'numeric');
            app.ConstanteProportionnelleEditField.ValueChangedFcn = createCallbackFcn(app, @ConstanteProportionnelleEditFieldValueChanged, true);
            app.ConstanteintgraleEditFieldLabel = uilabel(app.GridLayout4);
            app.ConstanteintgraleEditFieldLabel.Text = 'Constante intégrale';
            app.ConstanteIntegraleEditField = uieditfield(app.GridLayout4, 'numeric');
            app.ConstanteIntegraleEditField.ValueChangedFcn = createCallbackFcn(app, @ConstanteIntegraleEditFieldValueChanged, true);
            app.RsolutiondelapositionbitsLabel = uilabel(app.GridLayout4);
            app.RsolutiondelapositionbitsLabel.Text = 'Résolution de la position (bits)';
            app.ResolutionADCEditField = uieditfield(app.GridLayout4, 'numeric');
            app.ResolutionADCEditField.ValueChangedFcn = createCallbackFcn(app, @ResolutionADCEditFieldValueChanged, true);
            app.RsolutiondelacommandebitsLabel = uilabel(app.GridLayout4);
            app.RsolutiondelacommandebitsLabel.Text = 'Résolution de la commande (bits)';
            app.ResolutionDACEditField = uieditfield(app.GridLayout4, 'numeric');
            app.ResolutionDACEditField.ValueChangedFcn = createCallbackFcn(app, @ResolutionDACEditFieldValueChanged, true);
            app.LameLabel = uilabel(app.GridLayout4);
            app.LameLabel.Text = 'Lame';
            app.LargeurdelalamemLabel = uilabel(app.GridLayout4);
            app.LargeurdelalamemLabel.Text = 'Largeur de la lame (m)';
            app.LargeurLame = uieditfield(app.GridLayout4, 'numeric');
            app.LargeurLame.ValueChangedFcn = createCallbackFcn(app, @LargeurLameValueChanged, true);
            app.LongueurdelalamemLabel = uilabel(app.GridLayout4);
            app.LongueurdelalamemLabel.Text = 'Longueur de la lame (m)';
            app.LogueurLame = uieditfield(app.GridLayout4, 'numeric');
            app.LogueurLame.ValueChangedFcn = createCallbackFcn(app, @LogueurLameValueChanged, true);
            app.ModuledeYoungPaLabel = uilabel(app.GridLayout4);
            app.ModuledeYoungPaLabel.Text = 'Module de Young (Pa)';
            app.ModuleDeYoung = uieditfield(app.GridLayout4, 'numeric');
            app.ModuleDeYoung.ValueChangedFcn = createCallbackFcn(app, @ModuleDeYoungValueChanged, true);
            app.Coefficientdefrottementm1Label = uilabel(app.GridLayout4);
            app.Coefficientdefrottementm1Label.Text = 'Coefficient de frottement (m^-1)';
            app.CoefficientFrottement = uieditfield(app.GridLayout4, 'numeric');
            app.CoefficientFrottement.ValueChangedFcn = createCallbackFcn(app, @CoefficientFrottementValueChanged, true);
            app.HauteurdelalamemLabel_6 = uilabel(app.GridLayout4);
            app.HauteurdelalamemLabel_6.Text = 'Hauteur de la lame (m)';
            app.HauteurLame = uieditfield(app.GridLayout4, 'numeric');
            app.HauteurLame.ValueChangedFcn = createCallbackFcn(app, @HauteurLameValueChanged, true);
            app.EntresPanel = uipanel(app.GridLayout2);
            app.EntresPanel.Title = 'Entrées';
            app.GridLayout5 = uigridlayout(app.EntresPanel);
            app.MassedposegEditFieldLabel = uilabel(app.GridLayout5);
            app.MassedposegEditFieldLabel.Text = 'Masse déposée (g)';
            app.MassedposegEditField = uieditfield(app.GridLayout5, 'numeric');
            app.MassedposegEditField.ValueChangedFcn = createCallbackFcn(app, @MassedposegEditFieldValueChanged, true);
            app.MassedposegEditField.Value = 50;
            app.ChangementDeMasseCheckBox = uicheckbox(app.GridLayout5);
            app.ChangementDeMasseCheckBox.ValueChangedFcn = createCallbackFcn(app, @ChangementDeMasseCheckBoxValueChanged, true);
            app.ChangementDeMasseCheckBox.Text = 'Ajout d''une seconde masse';
            app.NouvellemassegEditFieldLabel = uilabel(app.GridLayout5);
            app.NouvellemassegEditFieldLabel.Text = 'Nouvelle masse (g)';
            app.NouvelleMasseEditField = uieditfield(app.GridLayout5, 'numeric');
            app.NouvelleMasseEditField.ValueChangedFcn = createCallbackFcn(app, @NouvelleMasseEditFieldValueChanged, true);
            app.NouvelleMasseEditField.Enable = 'off';
            app.NouvelleMasseEditField.Value = 25;
            app.MomentdarrivedelamassesEditFieldLabel = uilabel(app.GridLayout5);
            app.MomentdarrivedelamassesEditFieldLabel.Text = 'Moment d''arrivée de la masse (s)';
            app.MomentArriveeEditField = uieditfield(app.GridLayout5, 'numeric');
            app.MomentArriveeEditField.ValueChangedFcn = createCallbackFcn(app, @MomentArriveeEditFieldValueChanged, true);
            app.MomentArriveeEditField.Enable = 'off';
            app.MomentArriveeEditField.Value = 16;
            app.MassetaregEditFieldLabel = uilabel(app.GridLayout5);
            app.MassetaregEditFieldLabel.Text = 'Masse tare (g)';
            app.MasseTareEditField = uieditfield(app.GridLayout5, 'numeric');
            app.MasseTareEditField.ValueChangedFcn = createCallbackFcn(app, @MasseTareEditFieldValueChanged, true);
            app.SimulationPanel = uipanel(app.GridLayout2);
            app.SimulationPanel.Title = 'Simulation';
            app.GridLayout3 = uigridlayout(app.SimulationPanel);
            app.LancerlasimulationButton = uibutton(app.GridLayout3, 'push');
            app.LancerlasimulationButton.ButtonPushedFcn = createCallbackFcn(app, @LancerlasimulationButtonPushed, true);
            app.LancerlasimulationButton.Text = 'Lancer la simulation';
            app.DuredelasimulationsEditField_2Label = uilabel(app.GridLayout3);
            app.DuredelasimulationsEditField_2Label.Text = 'Durée de la simulation (s)';
            app.DuredelasimulationsEditField_2 = uieditfield(app.GridLayout3, 'text');
            app.DuredelasimulationsEditField_2.ValueChangedFcn = createCallbackFcn(app, @DuredelasimulationsEditField_2ValueChanged, true);
            app.DuredelasimulationsEditField_2.Value = '24';
            app.BalayagedeparamtresPanel = uipanel(app.GridLayout2);
            app.BalayagedeparamtresPanel.Title = 'Balayage de paramètres';
            app.GridLayout6 = uigridlayout(app.BalayagedeparamtresPanel);
            app.ParamtrebalayDropDown_2Label = uilabel(app.GridLayout6);
            app.ParamtrebalayDropDown_2Label.Text = 'Paramètre balayé';
            app.ParametreBalayeDropDown = uidropdown(app.GridLayout6);
            app.ParametreBalayeDropDown.Items = {'K_amp', 'K_c', 'N_bobine', 'B_bobine', 'r_bobine', 'R_bobine', 'R_eq', 'L_bobine', 'Kp', 'Ki', 'adc_entrees_resolution', 'dac_commande_resolution'};
            app.ParametreBalayeDropDown.ValueChangedFcn = createCallbackFcn(app, @ParametreBalayeDropDownValueChanged, true);
            app.ParametreBalayeDropDown.Value = 'K_amp';
            app.BalayageButton = uibutton(app.GridLayout6, 'push');
            app.BalayageButton.ButtonPushedFcn = createCallbackFcn(app, @BalayageButtonPushed, true);
            app.BalayageButton.Text = 'Balayer un paramètre';
            app.ValeurssimulerEditFieldLabel = uilabel(app.GridLayout6);
            app.ValeurssimulerEditFieldLabel.Text = 'Valeurs à simuler';
            app.BalayageValeursEditField = uieditfield(app.GridLayout6, 'text');
            app.BalayageValeursEditField.ValueChangedFcn = createCallbackFcn(app, @BalayageValeursEditFieldValueChanged, true);
            app.DescirptionParametreBalayeLabel = uilabel(app.GridLayout6);
            app.DescirptionParametreBalayeLabel.Text = 'Gain de l''amplificateur';
            app.DescriptionLabel = uilabel(app.GridLayout6);
            app.DescriptionLabel.Text = 'Description';
            app.OptionsdaffichagePanel = uipanel(app.GridLayout2);
            app.OptionsdaffichagePanel.Title = 'Options d''affichage';
            app.GridLayout7 = uigridlayout(app.OptionsdaffichagePanel);
            app.ModeDropDown_2Label = uilabel(app.GridLayout7);
            app.ModeDropDown_2Label.Text = 'Mode';
            app.ModeDropDown_2 = uidropdown(app.GridLayout7);
            app.ModeDropDown_2.Items = {'Masse totale', 'Comptage de pièces'};
            app.ModeDropDown_2.ItemsData = {'0', '1'};
            app.ModeDropDown_2.ValueChangedFcn = createCallbackFcn(app, @ModeDropDown_2ValueChanged, true);
            app.ModeDropDown_2.Value = '0';
            app.UnitsdemesureDropDown_2Label = uilabel(app.GridLayout7);
            app.UnitsdemesureDropDown_2Label.Text = 'Unités de mesure';
            app.UnitsdemesureDropDown_2 = uidropdown(app.GridLayout7);
            app.UnitsdemesureDropDown_2.Items = {'grammes', 'onces'};
            app.UnitsdemesureDropDown_2.ItemsData = {'0', '1'};
            app.UnitsdemesureDropDown_2.Value = '0';
            app.TypedepicesDropDownLabel = uilabel(app.GridLayout7);
            app.TypedepicesDropDownLabel.Text = 'Type de pièces';
            app.TypeDePiecesDropDown = uidropdown(app.GridLayout7);
            app.TypeDePiecesDropDown.Items = {'5¢', '10¢', '25¢', '1.00$', '2.00$'};
            app.TypeDePiecesDropDown.ItemsData = {'0', '1', '2', '3', '4'};
            app.TypeDePiecesDropDown.Enable = 'off';
            app.TypeDePiecesDropDown.Value = '0';
            app.ResultatsTab = uitab(app.MainTabGroup);
            app.ResultatsTab.Title = 'Résultats';
            app.GridLayout8 = uigridlayout(app.ResultatsTab);
            app.PerformancePanel = uipanel(app.GridLayout8);
            app.PerformancePanel.Title = 'Performance';
            app.GridLayout11 = uigridlayout(app.PerformancePanel);
            app.TempsStabilisationLabel = uilabel(app.GridLayout11);
            app.TempsStabilisationLabel.Text = 'Temps de stabilisation: N/A';
            app.JustesseLabel = uilabel(app.GridLayout11);
            app.JustesseLabel.Text = 'Justesse: N/A';
            app.PrecisionLabel = uilabel(app.GridLayout11);
            app.PrecisionLabel.Text = 'Précision: N/A';
            app.Execution_Time = uilabel(app.GridLayout11);
            app.Execution_Time.Text = 'Temps d''exécution: N/A';
            app.GraphiquesPanel = uipanel(app.GridLayout8);
            app.GraphiquesPanel.Title = 'Graphiques';
            app.GridLayout9 = uigridlayout(app.GraphiquesPanel);
            app.AxesMasseReelle = uiaxes(app.GridLayout9);
            title(app.AxesMasseReelle, 'Masse réelle')
            xlabel(app.AxesMasseReelle, 'Temps (s)')
            ylabel(app.AxesMasseReelle, 'Masse (g)')
            app.AxesMasseMesuree = uiaxes(app.GridLayout9);
            title(app.AxesMasseMesuree, 'Masse mesurée')
            xlabel(app.AxesMasseMesuree, 'Temps (s)')
            ylabel(app.AxesMasseMesuree, 'Masse (g)')
            app.AxesLame = uiaxes(app.GridLayout9);
            title(app.AxesLame, 'Lame')
            xlabel(app.AxesLame, 'Temps (s)')
            ylabel(app.AxesLame, 'Déflexion (mm)')
            app.AxesCommande = uiaxes(app.GridLayout9);
            title(app.AxesCommande, 'Commande')
            xlabel(app.AxesCommande, 'Temps (s)')
            ylabel(app.AxesCommande, 'Commande (V)')
            app.AffichagegraphiquePanel = uipanel(app.GridLayout8);
            app.AffichagegraphiquePanel.Title = 'Affichage graphique';
            app.GridLayout10 = uigridlayout(app.AffichagegraphiquePanel);
            app.LCD = uilabel(app.GridLayout10);
            app.LCD.Text = 'N/A';
            app.FichiersPanel = uipanel(app.GridLayout8);
            app.FichiersPanel.Title = 'Fichiers';
            app.GridLayout12 = uigridlayout(app.FichiersPanel);
            app.ChargerResultatsButton = uibutton(app.GridLayout12, 'push');
            app.ChargerResultatsButton.ButtonPushedFcn = createCallbackFcn(app, @ChargerResultatsButtonPushed, true);
            app.ChargerResultatsButton.Text = 'Charger des résultats';
            app.SauvegarderResultatsButton = uibutton(app.GridLayout12, 'push');
            app.SauvegarderResultatsButton.ButtonPushedFcn = createCallbackFcn(app, @SauvegarderResultatsButtonPushed, true);
            app.SauvegarderResultatsButton.Enable = 'off';
            app.SauvegarderResultatsButton.Text = 'Sauvegarder des résultats';
            app.ComparaisonTab = uitab(app.MainTabGroup);
            app.ComparaisonTab.Title = 'Comparaison';
            app.GridLayout13 = uigridlayout(app.ComparaisonTab);
            app.FichiersPanel_2 = uipanel(app.GridLayout13);
            app.FichiersPanel_2.Title = 'Fichiers';
            app.GridLayout14 = uigridlayout(app.FichiersPanel_2);
            app.ChargerPremierModeleButton = uibutton(app.GridLayout14, 'push');
            app.ChargerPremierModeleButton.ButtonPushedFcn = createCallbackFcn(app, @ChargerPremierModeleButtonPushed, true);
            app.ChargerPremierModeleButton.Text = 'Charger le premier modèle';
            app.ChargerSecondModeleButton = uibutton(app.GridLayout14, 'push');
            app.ChargerSecondModeleButton.ButtonPushedFcn = createCallbackFcn(app, @ChargerSecondModeleButtonPushed, true);
            app.ChargerSecondModeleButton.Text = 'Charger le second modèle';
            app.GraphiquesPanel_2 = uipanel(app.GridLayout13);
            app.GraphiquesPanel_2.Title = 'Graphiques';
            app.GridLayout15 = uigridlayout(app.GraphiquesPanel_2);
            app.AxesComparaisonMasseReelle = uiaxes(app.GridLayout15);
            title(app.AxesComparaisonMasseReelle, 'Masse réelle')
            xlabel(app.AxesComparaisonMasseReelle, 'Temps (s)')
            ylabel(app.AxesComparaisonMasseReelle, 'Masse (g)')
            app.AxesComparaisonCommande = uiaxes(app.GridLayout15);
            title(app.AxesComparaisonCommande, 'Commande')
            xlabel(app.AxesComparaisonCommande, 'Temps (s)')
            ylabel(app.AxesComparaisonCommande, 'Commande (V)')
            app.AxesComparaisonMasseMesuree = uiaxes(app.GridLayout15);
            title(app.AxesComparaisonMasseMesuree, 'Masse mesurée')
            xlabel(app.AxesComparaisonMasseMesuree, 'Temps (s)')
            ylabel(app.AxesComparaisonMasseMesuree, 'Masse (g)')
            app.AxesComparaisonLame = uiaxes(app.GridLayout15);
            title(app.AxesComparaisonLame, 'Lame')
            xlabel(app.AxesComparaisonLame, 'Temps (s)')
            ylabel(app.AxesComparaisonLame, 'Déflexion (mm)')
            app.PerformancedupremiermodlePanel = uipanel(app.GridLayout13);
            app.PerformancedupremiermodlePanel.Title = 'Performance du premier modèle';
            app.GridLayout16 = uigridlayout(app.PerformancedupremiermodlePanel);
            app.JustesseComparaisonLabel1 = uilabel(app.GridLayout16);
            app.JustesseComparaisonLabel1.Text = 'Justesse: N/A';
            app.PrecisionComparaisonLabel1 = uilabel(app.GridLayout16);
            app.PrecisionComparaisonLabel1.Text = 'Précision: N/A';
            app.TempsStabilisationComparaisonLabel1 = uilabel(app.GridLayout16);
            app.TempsStabilisationComparaisonLabel1.Text = 'Temps de stabilisation: N/A';
            app.PerformancedusecondmodlePanel = uipanel(app.GridLayout13);
            app.PerformancedusecondmodlePanel.Title = 'Performance du second modèle';
            app.GridLayout17 = uigridlayout(app.PerformancedusecondmodlePanel);
            app.JustesseComparaisonLabel2 = uilabel(app.GridLayout17);
            app.JustesseComparaisonLabel2.Text = 'Justesse: N/A';
            app.TempsStabilisationComparaisonLabel2 = uilabel(app.GridLayout17);
            app.TempsStabilisationComparaisonLabel2.Text = 'Temps de stabilisation: N/A';
            app.PrecisionComparaisonLabel2 = uilabel(app.GridLayout17);
            app.PrecisionComparaisonLabel2.Text = 'Précision: N/A';
            % Show the figure after all components are created
            app.BalancelectroniqueasservieOutildeCAOUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BalancelectroniqueasservieOutildeCAOUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BalancelectroniqueasservieOutildeCAOUIFigure)
        end
    end
end